classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        stormsimLogoImage               matlab.ui.control.Image
        projectPanel                    matlab.ui.container.Panel
        storm_duration                  matlab.ui.control.NumericEditField
        HazardTypeDurationhrLabel_2     matlab.ui.control.Label
        create_whp                      matlab.ui.control.CheckBox
        create_wlp                      matlab.ui.control.CheckBox
        AlternateDatasetsLabel          matlab.ui.control.Label
        use_peaks                       matlab.ui.control.CheckBox
        use_timeseries                  matlab.ui.control.CheckBox
        FidelityLabel                   matlab.ui.control.Label
        SeaLevelRiseSLRTypeDropDown     matlab.ui.control.DropDown
        SeaLevelRiseSLRTypeDropDownLabel  matlab.ui.control.Label
        newProjectButton                matlab.ui.control.Button
        ForcingDatum                    matlab.ui.control.EditField
        ForcingDatumEditField_4Label    matlab.ui.control.Label
        prob_mass_source                matlab.ui.control.EditField
        pmButton                        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SourceDropDownLabel_3           matlab.ui.control.Label
        project_CLs                     matlab.ui.control.EditField
        ConfidenceLimitsEditFieldLabel_2  matlab.ui.control.Label
        TidesTypeDropDown               matlab.ui.control.DropDown
        TidesTypeDropDownLabel          matlab.ui.control.Label
        SourceDropDownLabel_2           matlab.ui.control.Label
        chs_zip                         matlab.ui.control.EditField
        chsZipButton                    matlab.ui.control.Button
        DefineProjectForcingMetadataLabel  matlab.ui.control.Label
        storm_sampling                  matlab.ui.control.DropDown
        HazardTypeDurationhrLabel       matlab.ui.control.Label
        case_name                       matlab.ui.control.EditField
        AlternativeIDEditFieldLabel     matlab.ui.control.Label
        struc_id                        matlab.ui.control.EditField
        TransectIDEditFieldLabel        matlab.ui.control.Label
        project_name                    matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        DefineProjectMetadataLabel      matlab.ui.control.Label
        DefineProjectOutputsExportPathLabel  matlab.ui.control.Label
        outpathEditField                matlab.ui.control.EditField
        outpathButton                   matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        midPanelBlock                   matlab.ui.control.Image
        tidalGagePanel                  matlab.ui.container.Panel
        PSEClassDropDownLabel_14        matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.StateButton
        Panel_3                         matlab.ui.container.Panel
        ForcingUIAxes_3                 matlab.ui.control.UIAxes
        PSEClassDropDownLabel_13        matlab.ui.control.Label
        PSEClassDropDownLabel_12        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DatumDropDown                   matlab.ui.control.DropDown
        DatumLabel                      matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        CreateProjectPSETab             matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        ForcingUIAxes                   matlab.ui.control.UIAxes
        no_berm                         matlab.ui.control.CheckBox
        add_berm                        matlab.ui.control.CheckBox
        PlaceBermLabel                  matlab.ui.control.Label
        PSEDatumEditField_2             matlab.ui.control.EditField
        PSEDatumEditField_2Label        matlab.ui.control.Label
        struc_type                      matlab.ui.control.DropDown
        PSEClassDropDown_2Label         matlab.ui.control.Label
        DefineProtectiveSystemElementLabel_2  matlab.ui.control.Label
        PSEClassDropDownLabel_4         matlab.ui.control.Label
        pseParamTable                   matlab.ui.control.Table
        ProjectForcingTab               matlab.ui.container.Tab
        tidalGageFileDonwloadPanel      matlab.ui.container.Panel
        StationIDEditFieldLabel_2       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Button                          matlab.ui.control.Button
        StationIDEditField              matlab.ui.control.EditField
        StationIDEditFieldLabel         matlab.ui.control.Label
        tidalGageFileDonwloadPanel_2    matlab.ui.container.Panel
        outpathEditField_3              matlab.ui.control.EditField
        outpathButton_3                 matlab.ui.control.Button
        TidesEditField_4Label           matlab.ui.control.Label
        noTidesEdtiField                matlab.ui.control.EditField
        SLREditField_4                  matlab.ui.control.EditField
        SLREditField_4Label             matlab.ui.control.Label
        PlotFIeldDropDown               matlab.ui.control.DropDown
        PlotFIeldDropDownLabel          matlab.ui.control.Label
        apply_depth_limitation_on       matlab.ui.control.CheckBox
        apply_depth_limitation_off      matlab.ui.control.CheckBox
        DepthLimitationLabel            matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SLREditField_4Label_2           matlab.ui.control.Label
        outpathEditField_5              matlab.ui.control.EditField
        outpathButton_5                 matlab.ui.control.Button
        ProjectForcingAdjustmentsLabel  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        ForcingUIAxes_2                 matlab.ui.control.UIAxes
        AllStormsCheckBox               matlab.ui.control.CheckBox
        StormToDisplaySpinner           matlab.ui.control.Spinner
        StormToDisplayLabel             matlab.ui.control.Label
        PlotTypeDropDown                matlab.ui.control.DropDown
        PlotTypeDropDownLabel           matlab.ui.control.Label
        ProjectForcingScreeningLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        rightPanelBlock                 matlab.ui.control.Image
        Panel_6                         matlab.ui.container.Panel
        use_aep                         matlab.ui.control.Switch
        PSEClassDropDownLabel_18        matlab.ui.control.Label
        WorkflowDropDown                matlab.ui.control.DropDown
        WorkflowDropDownLabel           matlab.ui.control.Label
        NotreadyLabel                   matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        RunButton                       matlab.ui.control.Button
        restarPSEUncert                 matlab.ui.control.Button
        PSEClassDropDownLabel_9         matlab.ui.control.Label
        restartForcingUncert            matlab.ui.control.Button
        PSEClassDropDownLabel_11        matlab.ui.control.Label
        PSEClassDropDownLabel_15        matlab.ui.control.Label
        Tree_2                          matlab.ui.container.CheckBoxTree
        RunupR2Node_2                   matlab.ui.container.TreeNode
        OvertoppingqNode_2              matlab.ui.container.TreeNode
        TotalOvertoppingRateQNode_2     matlab.ui.container.TreeNode
        PSEClassDropDownLabel_17        matlab.ui.control.Label
        PSEClassDropDownLabel_16        matlab.ui.control.Label
        SetupProjectUncertaintyLabel    matlab.ui.control.Label
        forcingUncertainty              matlab.ui.control.Table
        PSEUncertainty                  matlab.ui.control.Table
        Tree                            matlab.ui.container.CheckBoxTree
        RunupR2Node                     matlab.ui.container.TreeNode
        OvertoppingqNode                matlab.ui.container.TreeNode
        TotalOvertoppingRateQNode       matlab.ui.container.TreeNode
        SetupProjectResponsesRunStromSimLabel  matlab.ui.control.Label
        forcing_type                    matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        config % Description
        CHS_Data % Description
        storm % Description
        project_forcing % Description
        Resp % Description
    end

    properties (Access = private)
        default_PSEu % Description
        default_forcingu % Description
        input_template % Description
        structure % Description
    end

    methods (Access = private)

        function config = inputParser(app, fname)
            %% GRAB AND DEFINE INPUTS
            % Define Symbol Column Header (Serves As Anchor Points For Searches)
            str_ref = 'Model Variable Symbol';
            % Define Anchor Point Offset (Location Of First Data Point)
            anchor_offset = 1;
            next_tbl_offset = 3;
            % Read StormSim Project Input File
            input_file = readcell(fname);
            % Add Filename To Config
            config.stormsim_input_file = fname;

            %% PARSE INPUT FILE AND CREATE CONFIG STRUCTURE
            % Find Row And Column Index For "str_ref" In "input_file"
            [row_indx,col_indx] = find(cell2mat(cellfun(@(x) strcmp(x,str_ref),input_file,'un',false))==1);
            [row_indx2,col_indx2] = find(cell2mat(cellfun(@(x) strcmp(x,'Value'),input_file,'un',false))==1);
            % Loop Through Found Instances (Matched Cases Represent Input Tables)
            for ii= 1:length(row_indx)
                % Extract Input Table
                if ii == length(row_indx2)
                    helper_var = input_file(row_indx(ii)+anchor_offset:end,col_indx(ii):col_indx2(ii));
                    helper_var = helper_var(sum(cellfun(@isempty,helper_var),2)<4, :);
                else
                    if row_indx(ii+1) == row_indx(1)
                        helper_var = input_file(row_indx(ii)+anchor_offset:end,col_indx(ii):col_indx2(ii));
                    else
                        helper_var = input_file(row_indx(ii)+anchor_offset:row_indx(ii+1)-next_tbl_offset,col_indx(ii):col_indx2(ii));
                    end
                end
                % Remove Is Missing
                helper_var = helper_var(~cell2mat(cellfun(@(x) isa(x,'missing'),helper_var(:,1),'UniformOutput',false)),:);
                % Fill Others
                helper_var(cell2mat(cellfun(@(x) isa(x,'missing'),helper_var(:,end),'UniformOutput',false)),end) = {''};
                % Loop Through Input Table Items And Create Config
                for jj = 1:size(helper_var, 1)
                    % Create "config" Structure Field
                    config.(helper_var{jj,1}) = helper_var{jj,end};
                end
            end
            % Make Temp Path Empty
            config.temp_path = '';
        end

        function Data_out = call_chs_h5_converter(app, files_2_convert)
            % initialize Counter
            ctr = 1;
            d = uiprogressdlg(app.UIFigure,'Title','Converting CHS h5 files...');
            % Loop Through H5 Files In Zip Folder
            for jj = 1:length(files_2_convert)
                [~, fname, fext] = fileparts(files_2_convert{jj});
                d.Message = ['Processing ' fname fext];
                d.Value = jj/length(files_2_convert);
                % Call H5 Converter
                [cData] = chs_h5_converter(files_2_convert{jj});
                % Store Converted Filename
                Data_out(ctr).Filename = files_2_convert{jj};
                % Store Converted Data
                Data_out(ctr).Conv_Data = cData;
                % Increment Counter
                ctr = ctr + 1;
            end
            % Remove Temp Dir
            delete(app.config.temp_path)
            close(d);
        end

        function app = table_initialize(app, u_names, wObj)
            % Read Template
            temp = app.input_template;
            % Find Model Variable Column
            [rr, cc] = cellfun(@(x) find(x==1), cellfun(@(x) strcmp(x,temp), u_names, 'un', false), 'un', false);
            rr = cell2mat(rr);cc = cell2mat(cc);
            % Build Data
            tdata = temp(rr,cc:cc+3);
            tdata(:,2) = [];tdata = tdata(:,[1,3,2]);
            eval(['app.' wObj '.Data = tdata;']);
        end

        function app = panel_unlock_check(app)
            % Define Pass Count Based On Storm Sampling
            switch app.storm_sampling.Value
                case 'XC'
                    pass_cnt = 6;
                otherwise
                    pass_cnt = 7;
            end
            % Check If All Boxes Are Filled
            chk = sum([~isempty(app.outpathEditField.Value);...
                ~isempty(app.project_name.Value);...
                ~isempty(app.struc_id.Value);...
                ~isempty(app.case_name.Value);...
                ~isempty(app.project_CLs.Value);...
                ~isempty(app.chs_zip.Value);...
                ~isempty(app.prob_mass_source.Value)]) == pass_cnt;
            % Check Project CLs
            chk2 = [contains(app.project_CLs.Value, '['), contains(app.project_CLs.Value, ']')];
            try
                chk2 = strsplit(app.project_CLs.Value,{'[',']',', ' ,','});
                chk2 = cellfun(@str2double, chk2(2:end-1));
                if any(isnan(chk2))
                    chk2 = 0;
                    app.project_CLs.Value = '';
                else
                    chk2 = isnumeric(chk2);
                end
            catch
                chk2 = 0;
                app.project_CLs.Value = '';
            end
            % Unlock Panel
            if chk == 1 && chk2 == 1
                % Unlock Panel
                app.midPanelBlock.Visible = 'off';
            else
                app.midPanelBlock.Visible = 'on';
            end
        end

        function  plot_structure_geometry(app)
            warning('off','all');

            % Define Structure Type
            structure_type = app.struc_type.Value;
            % Datum
            datum = app.PSEDatumEditField_2.Value;
            %
            structure = app.structure;
            %% DEFINE FIGURE PROPERTIES
            % Title Font
            font_title = 15;
            % Axis Title
            font_axes = font_title - 3;

            %% INITIALIZE & FORMAT FIGURE/AXES
            % Initialize Figure Axes
            ax = app.ForcingUIAxes;
            % Enable Hold Properties Option
            hold(ax,'on');
            % Turn On Axes Grid
            grid(ax,'on');
            grid(ax,'minor');
            % Define Axes Font Size
            ax.FontSize = font_axes;
            ax.FontWeight = 'bold';
            % Labels
            xlabel(ax, 'X Distance [m]');
            ylabel(ax, ['Elevation ,' datum ' [m]']);

            %% CREATE STRUCTURE GEOMETRY
            % Define Toe Elevation
            toe = structure.('toe_elevation');
            % Define Crest Elevation
            crest_elevation = structure.('crest_elevation');
            % Define Crest Width
            crest_width = structure.('crest_width');
            switch structure_type
                case 1 % Levee
                    % Define Seward Slope
                    seaside_slope = structure.('seaside_slope');
                    % Define Landward Slope
                    leeside_slope = structure.('leeside_slope');
                    % Initialize Shape Variables
                    xs = zeros(1,5);ys=xs;
                    % Point 1 - Seaside Toe
                    ys(1) = toe;xs(1) = 0;
                    % Point 2 - Toe to Seaside Crest Path
                    b = (-1/seaside_slope)*xs(1)+ys(1);% Y Intercept of line
                    ys(2) = crest_elevation;xs(2) = (ys(2) - b)*seaside_slope;
                    % Point 3 - Crest Width
                    ys(3) = ys(2);xs(3) = xs(2) + crest_width;
                    % Point 4 - Leeside Toe
                    b = (1/leeside_slope)*xs(3)+ys(3);
                    ys(4) = ys(1);xs(4) = (ys(4) - b)*-leeside_slope;
                    % Point 5
                    xs(5) = xs(1);ys(5) = ys(1);
                    % Horizontal Shift (Make Structure Centralized)
                    xs = xs + 1;
                case 2 % Floodwalls
                    %%%%% Create Berm
                    % Define Berm Slope
                    berm_slope = structure.('berm_slope');
                    % Define Berm Elevation
                    berm_elev = structure.('berm_elevation');
                    % Define Berm Width
                    berm_width = structure.('berm_width');
                    % Initialize Shape Variables
                    xs_berm = zeros(1,4);ys_berm=xs_berm;
                    % Wall Toe
                    xs_berm(1) = 0;ys_berm(1) = toe;
                    % Point 2 - Toe to Seaside Crest Path
                    b = (-1/berm_slope)*xs_berm(1)+ys_berm(1);
                    % Y Intercept of line
                    ys_berm(2) = berm_elev;xs_berm(2) = (ys_berm(2) - b)*berm_slope;
                    % Point 3 - Crest Width
                    ys_berm(3) = ys_berm(2);xs_berm(3) = xs_berm(2) + berm_width;
                    % Wall Width (Width Is Visual Only)
                    xs_berm(4) = xs_berm(3) + 0.25+0.5;ys_berm(4) = ys_berm(3);
                    xs_berm(5) = xs_berm(4);ys_berm(5) = ys_berm(1);
                    %%% Create Floodwall
                    % Define Wall Bottom Elevation
                    wall_bottom = structure.('wall_bottom_elevation');
                    % Initialize Shape Variables
                    xs = zeros(1,5);ys=xs;
                    % Wall Toe
                    xs(1) = xs_berm(3);ys(1) = wall_bottom;
                    % Wall Toe To Crest Path
                    xs(2) =  xs(1);ys(2) = (crest_elevation);
                    % Wall Width (Width Is Visual Only)
                    xs(3) = xs(2) + 0.25;ys(3) = ys(2);
                    % Crest to Leeside Toe Path
                    xs(4) = xs(3);ys(4) = ys(1);
                    % Close Polyshape
                    xs(5) = xs(1);ys(5) = ys(1);
                case 3 % Rubblemound
                    % Define Seward Slope
                    seaside_slope = structure.('seaside_slope');
                    % Define Landward Slope
                    leeside_slope = structure.('leeside_slope');
                    % Initialize Shape Variables
                    xs = zeros(1,5);ys=xs;
                    % Point 1 - Seaside Toe
                    ys(1) = toe;xs(1) = 0;
                    % Point 2 - Toe to Seaside Crest Path
                    b = (-1/seaside_slope)*xs(1)+ys(1);% Y Intercept of line
                    ys(2) = crest_elevation;xs(2) = (ys(2) - b)*seaside_slope;
                    % Point 3 - Crest Width
                    ys(3) = ys(2);xs(3) = xs(2) + crest_width;
                    % Point 4 - Leeside Toe
                    b = (1/leeside_slope)*xs(3)+ys(3);
                    ys(4) = ys(1);xs(4) = (ys(4) - b)*-leeside_slope;
                    % Point 5
                    xs(5) = xs(1);ys(5) = ys(1);
            end

            %% FORMAT AXIS LIMITS AND PLOT GEOMETRIES
            % Define Axis Limits
            if structure_type~=2
                % Horizontal Shift (Make Structure Centralized)
                xs = xs + 4;
                % Define Limits
                ax.XLim = [0 max(xs)+1];
            else
                xs_berm = xs_berm + 1;
                xs = xs + 1;
                ax.XLim = [0 max([xs;xs_berm],[],'all')];
                % Create Berm Geo
                berm_geo = polyshape(xs_berm,ys_berm);
                % Plot Structure
                plot(ax,berm_geo,'FaceColor',[0.65,0.65,0.65],'FaceAlpha',1);
            end
            ax.YLim = [toe-0.5 max(ys)+0.5];
            % Create Structure Polyshape
            struc_geo = polyshape(xs,ys);
            % Create "Sand" Polyshape
            sand_geo = polyshape([ax.XLim(1) ax.XLim(1) ax.XLim(2)+4 ax.XLim(2)+4],[toe-6 toe toe toe-6]);
            % Plot Structure
            plot(ax,struc_geo,'FaceColor',[0.65,0.65,0.65],'FaceAlpha',1);
            % Plot Sand
            plot(ax,sand_geo,'FaceColor',[0.9 0.72 0.31],'FaceAlpha',1);

            warning('on','all');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read-In Template
            app.config = inputParser(app, 'StormSim_Inputs.xlsx');
            % Store Template For Descriptions
            app.input_template = readcell('StormSim_Inputs.xlsx');
            % Initialize Forcing Uncertainty Tables
            u_names = fieldnames(app.config);
            u_names = u_names(contains(u_names, {'u_a','u_r'}));
            app = table_initialize(app, u_names, 'forcingUncertainty');
            % Initialize PSE Uncertainty Tables
            app = table_initialize(app, {'r2p_u','q_u'}, 'PSEUncertainty');
            % Define Structural Parameters Based on Structure Type
            switch app.struc_type.Value
                case 1 % Levee
                    % Define "config" Vars To Grab
                    vars_2_grab = {'crest_elevation','crest_width','toe_elevation','seaside_slope',...
                        'leeside_slope','structure_dir','berm_width','berm_elevation',...
                        'water_density','roughness_ifactor', 'berm_slope'};
                case 2 % Floodwall
                    % Define "config" Vars To Grab
                    vars_2_grab = {'crest_elevation','crest_width','toe_elevation','seaside_slope','leeside_slope'....
                        'structure_dir','berm_width','berm_elevation',...
                        'water_density','roughness_ifactor', 'berm_slope', 'wall_bottom_elevation'};
                case 3 % Rubblemound
                    % Define Vars To Grab
                    vars_2_grab = {'crest_elevation','crest_width','toe_elevation','seaside_slope',...
                        'leeside_slope','structure_dir','berm_width','berm_elevation','berm_slope',...
                        'armor_delta','lcbw_mass','seaside_mass','leeside_mass','water_density','cem_P',...
                        'seaside_limit_S','leeside_limit_S','seaside_init_S','leeside_init_S',...
                        'roughness_ifactor'};
            end
            % Initialize Structural Parameters Table
            app = table_initialize(app, vars_2_grab, 'pseParamTable');
            % Add Dummy Datum
            app.PSEDatumEditField_2.Value = 'MSL';
            % Create SS Structure Var
            for ii = 1:length(vars_2_grab)
                dummy = app.pseParamTable.Data(strcmp(vars_2_grab{ii}, app.pseParamTable.Data(:, 1)), 2);
                app.structure.(vars_2_grab{ii}) = dummy{:};
            end
            % Initialize PSE Plot
            plot_structure_geometry(app);


        end

        % Button pushed function: outpathButton
        function browseOutfolder(app, event)
            % Open
            selPath = uigetdir(pwd,'Choose StormSim project outputs folder location');
            % If User Pressed Cancel
            if selPath == 0
                % Set Edit Field To Empty
                app.outpathEditField.Value = '';
                app.config.outfolder = '';
            else
                % Write Path To Edit Box
                app.outpathEditField.Value = selPath;
                app.config.outfolder = selPath;
            end
            % Panel Check
            app = panel_unlock_check(app);
        end

        % Value changed function: WorkflowDropDown
        function WorkflowDropDownValueChanged(app, event)
            % Get Workflow Value
            value = app.WorkflowDropDown.Value;
            % Make Object Visible/Invisible Acording To Workflow
            switch value
                case {1, 3}

                case 2

            end
            app.config.workflow = value;
        end

        % Button pushed function: chsZipButton
        function chsZipUpload(app, event)
            % Upload CHS Zip Folder
            [file, pth] = uigetfile({'*.zip'}, 'Choose downloaded CHS zip folder', pwd);
            % Grab Config Fields
            config = app.config; %#ok<*ADPROPLC>
            % Storm Sampling
            storm_sampling = app.storm_sampling.Value;
            % Use Timeseries
            use_timeseries =  app.use_timeseries.Value;
            % Use Peaks
            use_peaks =  app.use_peaks.Value;
            % Execute Acording To User Response
            if file == 0
                % Execute When User Clicks Cancel
                % Change Color
                app.chsZipButton.BackgroundColor = [1 0 0];
                app.chs_zip.Value = '';
                app.config.chs_zip = '';
            else % User selected a zip folder
                % Dispaly file
                app.chs_zip.Value = file;
                % Change Pointer
                app.UIFigure.Pointer = 'watch';
                %
                if ~exist(fullfile(app.outpathEditField.Value, app.project_name.Value, app.struc_id.Value, app.case_name.Value),'dir')
                    mkdir(fullfile(app.outpathEditField.Value, app.project_name.Value, app.struc_id.Value, app.case_name.Value));
                end
                % Execute Acording To Data Source
                switch app.forcing_type.Value
                    case 1 % CHS Native Dataset
                        % Get File Parts
                        [~, ~, fext] = fileparts(file);
                        % Exectue Acording To File Type
                        switch fext
                            case '.mat' % Loading Previous Folder

                            case '.zip'
                                if exist(config.chs_zip,'file')==2 % Valid Zip Folder Detected
                                    % Create Temp Folder
                                    if exist('Temp','dir')==7
                                        % Determine How Many Temp Dirs Are Present
                                        temp_list = dir('Temp*');
                                        % Build Temp Folder Name
                                        config.temp_path = ['Temp_' num2str(length(temp_list) + 1)];
                                        % Assign Variable For Convenience
                                        temp_path = config.temp_path;
                                        % Create Temp Folder
                                        mkdir(temp_path);
                                    else % Temp Does Not Exist
                                        config.temp_path = 'Temp';
                                        temp_path = config.temp_path;
                                        mkdir('Temp');
                                    end
                                    % Decompress Zip Folder
                                    unzip(config.chs_zip,temp_path);
                                    % Scan Unzip Directory
                                    temp_dir = dir([temp_path filesep '**' filesep '*.h5']);
                                    % Add Field To config
                                    config.chs_files_2_convert = sortrows([{temp_dir.folder}', {temp_dir.name}'],2,'ascend');
                                    % ADCIRC Indx
                                    ad_indx = find(contains(config.chs_files_2_convert(:,2),'ADCIRC') == 1);
                                    % Grab CHS Identifiers
                                    chs_ident = strsplit(config.chs_files_2_convert{ad_indx(1),2},'_');% [Region Storm_Type Sim_Type Post_Type SP_ID Model File_Type]
                                    % Add CHS Region
                                    config.region = chs_ident{1};
                                    % Add CHS SP
                                    config.sp_ID = str2double(chs_ident{5}(3:end));
                                    % Grab ADCIRC Files
                                    CHS_file_ref = temp_dir(contains({temp_dir.name},{'ADCIRC'}));
                                    % Keep Only One File For Attributes
                                    CHS_file_ref = CHS_file_ref(1);
                                    % Datum
                                    chs_datum = h5info([CHS_file_ref(1).folder filesep CHS_file_ref(1).name]);
                                    chs_datum = chs_datum.Attributes;
                                    chs_datum = chs_datum(strcmp({chs_datum.Name},{'Vertical Datum'})).Value;
                                    app.ForcingDatum.Value = chs_datum;
                                    try
                                        % Get H5 Info
                                        sp_depth = h5info([CHS_file_ref(1).folder filesep CHS_file_ref(1).name]);
                                        % Need To Access Attributes Of First Storm
                                        sp_depth = sp_depth.Groups.Attributes;
                                        % Grab Save Point Depth
                                        config.chs_sp_depth = str2double(sp_depth(strcmp({sp_depth.Name},{'Save Point Depth'})).Value);
                                    end
                                    % Grab ADCIRC Files
                                    CHS_file_ref = temp_dir(~contains({temp_dir.name},{'ADCIRC'}));
                                    % Keep Only One File For Attributes
                                    CHS_file_ref = CHS_file_ref(1);
                                    % Grab CHS Identifiers
                                    chs_ident = strsplit(CHS_file_ref(1).name,'_');% [Region Storm_Type Sim_Type Post_Type SP_ID Model File_Type]
                                    % Add CHS Wave SP
                                    config.sp_ID_wave = str2double(chs_ident{5}(3:end));
                                    % Convert Files
                                    % Remove Unwanted Storm Types
                                    if strcmp(storm_sampling,'XC')
                                        chs_files_2_convert_paths = config.chs_files_2_convert(~contains(config.chs_files_2_convert(:,2),{'TC','TS'}), 1);
                                        chs_files_2_convert = config.chs_files_2_convert(~contains(config.chs_files_2_convert(:,2),{'TC','TS'}), 2);
                                    end
                                    if strcmp(storm_sampling,'TC')
                                        chs_files_2_convert_paths = config.chs_files_2_convert(contains(config.chs_files_2_convert(:,2),{'TC','TS'}), 1);
                                        chs_files_2_convert = config.chs_files_2_convert(contains(config.chs_files_2_convert(:,2),{'TC','TS'}), 2);
                                    end
                                    % Determine Minimum File Requirement For config
                                    [min_file_req, chs_files_2_convert,...
                                        chs_files_2_convert_paths] = minimum_file_requirement_check(chs_files_2_convert_paths, chs_files_2_convert,....
                                        storm_sampling, use_peaks, use_timeseries);
                                    % Append Filenames With Paths
                                    full_file_path = cellfun(@(x,y) [x filesep y],chs_files_2_convert_paths,chs_files_2_convert,'un',false);
                                    % Convert CHS Data
                                    CHS_Data = call_chs_h5_converter(app, full_file_path);
                                    app.CHS_Data = CHS_Data;
                                    % Build Name Prefix
                                    config.name_prefix = fullfile(config.project_name, config.struc_id,...
                                        [config.project_name '_' config.struc_id '_' config.region]);
                                    % Export Data
                                    save([config.name_prefix '_SP' num2str(config.sp_ID) '_raw_files.mat'], 'CHS_Data');
                                    % Remove Temp Dir
                                    if ~isempty(temp_path)
                                        rmdir(temp_path,'s');
                                    end
                                    % Fields To Store
                                    fields2change = {'temp_path', 'chs_files_2_convert', 'region', 'sp_ID', 'chs_sp_depth', 'sp_ID_wave', 'name_prefix'};
                                    % Loop Through Fields To Store
                                    for kk = 1:length(fields2change)
                                        % Store Into Schema
                                        app.config.(fields2change{kk}) = config.(fields2change{kk});
                                    end
                                    %
                                    app.config.project_datum = chs_datum;
                                else
                                    % Need Pop-up Error Window
                                end
                                % Change Color
                                app.chsZipButton.BackgroundColor = [0 1 0];
                        end
                    case 2

                end
            end
            app = panel_unlock_check(app);
            % Change Pointer
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: storm_sampling
        function storm_samplingValueChanged(app, event)
            value = app.storm_sampling.Value;
            %
            switch value
                case 'XC'
                    app.pmButton.Enable = 'off';
                case {'TC', 'CC'}
                    app.pmButton.Enable = 'on';
            end
            %
            app.config.storm_sampling = app.storm_sampling.Value;
            %
            app = panel_unlock_check(app);
        end

        % Value changed function: add_berm
        function add_bermValueChanged(app, event)
            value = app.add_berm.Value;
            %
            if value == 1
                % Set Other Off
                app.no_berm.Value = 0;
            else
                % Set Other On
                app.no_berm.Value = 1;
            end
        end

        % Value changed function: no_berm
        function no_bermValueChanged(app, event)
            value = app.no_berm.Value;
            %
            if value == 1
                % Set Other Off
                app.add_berm.Value = 0;
            else
                % Set Other On
                app.add_berm.Value = 1;
            end
        end

        % Value changed function: use_peaks
        function use_peaksValueChanged(app, event)
            %
            value = app.use_peaks.Value;
            %
            if value == 0
                % Peaks Was Turned Off
                app.use_timeseries.Value = true;
            end
            %
            if app.use_peaks.Value == 1 && app.use_timeseries.Value == 1
                % Enable WLP & WHP
                app.create_wlp.Enable = true;
                app.create_whp.Enable = true;
            else
                % Disable WLP & WHP
                app.create_wlp.Enable = false;
                app.create_whp.Enable = false;
            end
            % Store Into Schema
            app.config.use_peaks = app.use_peaks.Value;
            app.config.use_timeseries = app.use_timeseries.Value;
        end

        % Value changed function: use_timeseries
        function use_timeseriesValueChanged(app, event)
            value = app.use_timeseries.Value;
            %
            if value == 0
                % Timeseries Was Turned Off
                app.use_peaks.Value = true;
            end
            %
            if app.use_peaks.Value == 1 && app.use_timeseries.Value == 1
                % Enable WLP & WHP
                app.create_wlp.Enable = true;
                app.create_whp.Enable = true;
            else
                % Disable WLP & WHP
                app.create_wlp.Enable = false;
                app.create_whp.Enable = false;
            end
            % Store Into Scheema
            app.config.use_peaks = app.use_peaks.Value;
            app.config.use_timeseries = app.use_timeseries.Value;
        end

        % Value changed function: apply_depth_limitation_off
        function apply_depth_limitation_offValueChanged(app, event)
            value = app.apply_depth_limitation_off.Value;
            %
            if value == 0
                app.apply_depth_limitation_on.Value = true;
            else
                app.apply_depth_limitation_on.Value = false;
            end
        end

        % Button pushed function: restarPSEUncert
        function restarPSEUncertPushed(app, event)

        end

        % Button pushed function: restartForcingUncert
        function restartForcingUncertButtonPushed(app, event)

        end

        % Value changed function: TidesTypeDropDown
        function TidesTypeDropDownValueChanged(app, event)
            value = app.TidesTypeDropDown.Value;
            %
            switch value
                case 1
                    % No Tides
                    app.tidalGageFileDonwloadPanel.Visible = "on"; % Station Download
                    app.tidalGageFileDonwloadPanel_2.Visible = "on"; % Tidal File Upload
                case 2 % Station Download
                    app.tidalGageFileDonwloadPanel.Visible = "on"; % Station Download
                    app.tidalGageFileDonwloadPanel_2.Visible = "off"; % Tidal File Upload
                case 3 % Tidal File Upload
                    app.tidalGageFileDonwloadPanel.Visible = "off"; % Station Download
                    app.tidalGageFileDonwloadPanel_2.Visible = "on"; % Tidal File Upload
            end
        end

        % Value changed function: struc_type
        function struc_typeValueChanged(app, event)
            value = app.struc_type.Value;

        end

        % Value changed function: case_name, project_CLs, project_name, 
        % ...and 1 other component
        function editFields_callback(app, event)
            % Define Fields To Get
            fields2change = {'project_name','struc_id','case_name','project_CLs', 'storm_duration', 'storm_sampling'};
            % Loop Through Fields
            for ii = 1:length(fields2change)
                % If Is Empty Skip
                if isempty(fields2change{ii})
                    continue;
                else % Store On Schema
                    app.config.(fields2change{ii}) = app.(fields2change{ii}).Value;
                end
            end
            % Check For CHS Zip Unlock
            if sum(cell2mat(cellfun(@(x) isempty(app.(x).Value), {'project_name','struc_id','case_name'}, 'un', false))) == 3
                app.chsZipButton.Enable = true;
            else
                app.chsZipButton.Enable = false;
            end
            % Check If Next Panel Can Be Unlocked
            app = panel_unlock_check(app);
        end

        % Button pushed function: pmButton
        function pmButtonPushed(app, event)
            % Upload CHS Zip Folder
            [pm_folder] = uigetdir('Search for CHS Probability Mass Folder', pwd);
            %
            if pm_folder == 0
                % Change Color
                app.pmButton.BackgroundColor = [1 0 0];
                app.prob_mass_source.Value = '';
            else
                % Change Color
                app.pmButton.BackgroundColor = [0 1 0];
                app.config.prob_mass_source = pm_folder;
            end
            %
            app.config.prob_mass_source = pm_folder;
            %
            app = panel_unlock_check(app);
        end

        % Value changed function: create_wlp
        function create_wlpValueChanged(app, event)
            app.config.create_wlp = app.create_wlp.Value;
            app.config.create_whp = app.create_whp.Value;
        end

        % Button pushed function: newProjectButton
        function newProjectButtonPushed(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {572, 572, 572};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {572, 572};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {318, '1x', 315};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.projectPanel = uipanel(app.LeftPanel);
            app.outpathButton = uibutton(app.projectPanel, 'push');
            app.outpathButton.ButtonPushedFcn = createCallbackFcn(app, @browseOutfolder, true);
            app.outpathButton.Tooltip = {'Project output folder.'};
            app.outpathButton.Text = 'Browse';
            app.outpathEditField = uieditfield(app.projectPanel, 'text');
            app.outpathEditField.Editable = 'off';
            app.outpathEditField.Placeholder = 'Chose output location...';
            app.DefineProjectOutputsExportPathLabel = uilabel(app.projectPanel);
            app.DefineProjectOutputsExportPathLabel.Text = '1. Define Project Outputs Export Path';
            app.DefineProjectMetadataLabel = uilabel(app.projectPanel);
            app.DefineProjectMetadataLabel.Text = '2. Define Project Metadata';
            app.ProjectNameEditFieldLabel = uilabel(app.projectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name:';
            app.project_name = uieditfield(app.projectPanel, 'text');
            app.project_name.ValueChangedFcn = createCallbackFcn(app, @editFields_callback, true);
            app.project_name.Placeholder = 'Define project name...';
            app.TransectIDEditFieldLabel = uilabel(app.projectPanel);
            app.TransectIDEditFieldLabel.Text = 'Transect ID:';
            app.struc_id = uieditfield(app.projectPanel, 'text');
            app.struc_id.ValueChangedFcn = createCallbackFcn(app, @editFields_callback, true);
            app.struc_id.Placeholder = 'Define transect name...';
            app.AlternativeIDEditFieldLabel = uilabel(app.projectPanel);
            app.AlternativeIDEditFieldLabel.Text = 'Alternative ID:';
            app.case_name = uieditfield(app.projectPanel, 'text');
            app.case_name.ValueChangedFcn = createCallbackFcn(app, @editFields_callback, true);
            app.case_name.Placeholder = 'Define alternative name...';
            app.HazardTypeDurationhrLabel = uilabel(app.projectPanel);
            app.HazardTypeDurationhrLabel.Text = 'Hazard Type:';
            app.storm_sampling = uidropdown(app.projectPanel);
            app.storm_sampling.Items = {'XC', 'TC', 'CC'};
            app.storm_sampling.ItemsData = {'XC', 'TC', 'CC'};
            app.storm_sampling.ValueChangedFcn = createCallbackFcn(app, @storm_samplingValueChanged, true);
            app.storm_sampling.Value = 'XC';
            app.DefineProjectForcingMetadataLabel = uilabel(app.projectPanel);
            app.DefineProjectForcingMetadataLabel.Text = '3. Define Project Forcing Metadata';
            app.chsZipButton = uibutton(app.projectPanel, 'push');
            app.chsZipButton.ButtonPushedFcn = createCallbackFcn(app, @chsZipUpload, true);
            app.chsZipButton.Icon = fullfile(pathToMLAPP, 'Logos', 'search_icon_w.svg');
            app.chsZipButton.Enable = 'off';
            app.chsZipButton.Text = '';
            app.chs_zip = uieditfield(app.projectPanel, 'text');
            app.chs_zip.Editable = 'off';
            app.chs_zip.Placeholder = 'Upload CHS zip folder...';
            app.SourceDropDownLabel_2 = uilabel(app.projectPanel);
            app.SourceDropDownLabel_2.Text = 'Data Path:';
            app.TidesTypeDropDownLabel = uilabel(app.projectPanel);
            app.TidesTypeDropDownLabel.Text = 'Tides Type:';
            app.TidesTypeDropDown = uidropdown(app.projectPanel);
            app.TidesTypeDropDown.Items = {'No Tides', 'Random Sampling (Station)', 'Random Sampling (File)'};
            app.TidesTypeDropDown.ItemsData = [1 2 3];
            app.TidesTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TidesTypeDropDownValueChanged, true);
            app.TidesTypeDropDown.Placeholder = 'Choose project transect...';
            app.TidesTypeDropDown.Value = 1;
            app.ConfidenceLimitsEditFieldLabel_2 = uilabel(app.projectPanel);
            app.ConfidenceLimitsEditFieldLabel_2.Text = 'Confidence Limits:';
            app.project_CLs = uieditfield(app.projectPanel, 'text');
            app.project_CLs.ValueChangedFcn = createCallbackFcn(app, @editFields_callback, true);
            app.project_CLs.Placeholder = '[16, 30, 84, 96]';
            app.SourceDropDownLabel_3 = uilabel(app.projectPanel);
            app.SourceDropDownLabel_3.Text = 'TC Probability Masses';
            app.Label = uilabel(app.projectPanel);
            app.Label.Text = ':';
            app.pmButton = uibutton(app.projectPanel, 'push');
            app.pmButton.ButtonPushedFcn = createCallbackFcn(app, @pmButtonPushed, true);
            app.pmButton.Icon = fullfile(pathToMLAPP, 'Logos', 'search_icon_w.svg');
            app.pmButton.Enable = 'off';
            app.pmButton.Text = '';
            app.prob_mass_source = uieditfield(app.projectPanel, 'text');
            app.prob_mass_source.Editable = 'off';
            app.prob_mass_source.Placeholder = 'Probabilty mass folder...';
            app.ForcingDatumEditField_4Label = uilabel(app.projectPanel);
            app.ForcingDatumEditField_4Label.Text = 'Forcing Datum:';
            app.ForcingDatum = uieditfield(app.projectPanel, 'text');
            app.ForcingDatum.Editable = 'off';
            app.ForcingDatum.Placeholder = 'Waiting for data upload...';
            app.newProjectButton = uibutton(app.projectPanel, 'push');
            app.newProjectButton.ButtonPushedFcn = createCallbackFcn(app, @newProjectButtonPushed, true);
            app.newProjectButton.Icon = fullfile(pathToMLAPP, 'Logos', 'proj_icon_g.png');
            app.newProjectButton.Tooltip = {'New StromSim project. '};
            app.newProjectButton.Text = '';
            app.SeaLevelRiseSLRTypeDropDownLabel = uilabel(app.projectPanel);
            app.SeaLevelRiseSLRTypeDropDownLabel.Text = 'Sea Level Rise (SLR) Type:';
            app.SeaLevelRiseSLRTypeDropDown = uidropdown(app.projectPanel);
            app.SeaLevelRiseSLRTypeDropDown.Items = {'No SLR', 'Linear Super Position'};
            app.SeaLevelRiseSLRTypeDropDown.Placeholder = 'Choose project transect...';
            app.SeaLevelRiseSLRTypeDropDown.Value = 'No SLR';
            app.FidelityLabel = uilabel(app.projectPanel);
            app.FidelityLabel.Text = 'Fidelity:';
            app.use_timeseries = uicheckbox(app.projectPanel);
            app.use_timeseries.ValueChangedFcn = createCallbackFcn(app, @use_timeseriesValueChanged, true);
            app.use_timeseries.Text = 'Use Timeseries';
            app.use_timeseries.Value = true;
            app.use_peaks = uicheckbox(app.projectPanel);
            app.use_peaks.ValueChangedFcn = createCallbackFcn(app, @use_peaksValueChanged, true);
            app.use_peaks.Text = 'Use Peaks';
            app.AlternateDatasetsLabel = uilabel(app.projectPanel);
            app.AlternateDatasetsLabel.Text = 'Alternate Datasets:';
            app.create_wlp = uicheckbox(app.projectPanel);
            app.create_wlp.ValueChangedFcn = createCallbackFcn(app, @create_wlpValueChanged, true);
            app.create_wlp.Text = 'Build WLP';
            app.create_whp = uicheckbox(app.projectPanel);
            app.create_whp.Text = 'Build WHP';
            app.HazardTypeDurationhrLabel_2 = uilabel(app.projectPanel);
            app.HazardTypeDurationhrLabel_2.Text = 'Duration [hr]:';
            app.storm_duration = uieditfield(app.projectPanel, 'numeric');
            app.storm_duration.Limits = [1 Inf];
            app.storm_duration.Value = 1;
            app.stormsimLogoImage = uiimage(app.LeftPanel);
            app.stormsimLogoImage.ImageSource = 'SSLogo.png';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup_2 = uitabgroup(app.CenterPanel);
            app.CreateProjectPSETab = uitab(app.TabGroup_2);
            app.CreateProjectPSETab.Title = '4. Create Project PSE';
            app.pseParamTable = uitable(app.CreateProjectPSETab);
            app.pseParamTable.ColumnName = {'Field'; 'Value'; 'Units'};
            app.pseParamTable.RowName = {};
            app.pseParamTable.ColumnSortable = [true true false];
            app.PSEClassDropDownLabel_4 = uilabel(app.CreateProjectPSETab);
            app.PSEClassDropDownLabel_4.Text = 'PSE Geometry & Properties:';
            app.DefineProtectiveSystemElementLabel_2 = uilabel(app.CreateProjectPSETab);
            app.DefineProtectiveSystemElementLabel_2.Text = '4. Define Protective System Element';
            app.PSEClassDropDown_2Label = uilabel(app.CreateProjectPSETab);
            app.PSEClassDropDown_2Label.Text = 'PSE Class:';
            app.struc_type = uidropdown(app.CreateProjectPSETab);
            app.struc_type.Items = {'Levee', 'Floodwall', 'Rubble Mound'};
            app.struc_type.ItemsData = [1 2 3];
            app.struc_type.ValueChangedFcn = createCallbackFcn(app, @struc_typeValueChanged, true);
            app.struc_type.Placeholder = 'Choose project transect...';
            app.struc_type.Value = 1;
            app.PSEDatumEditField_2Label = uilabel(app.CreateProjectPSETab);
            app.PSEDatumEditField_2Label.Text = 'PSE Datum:';
            app.PSEDatumEditField_2 = uieditfield(app.CreateProjectPSETab, 'text');
            app.PSEDatumEditField_2.Editable = 'off';
            app.PSEDatumEditField_2.Placeholder = 'Define project PSE datum...';
            app.PlaceBermLabel = uilabel(app.CreateProjectPSETab);
            app.PlaceBermLabel.Text = 'Place Berm:';
            app.add_berm = uicheckbox(app.CreateProjectPSETab);
            app.add_berm.ValueChangedFcn = createCallbackFcn(app, @add_bermValueChanged, true);
            app.add_berm.Text = 'Yes';
            app.no_berm = uicheckbox(app.CreateProjectPSETab);
            app.no_berm.ValueChangedFcn = createCallbackFcn(app, @no_bermValueChanged, true);
            app.no_berm.Text = 'No';
            app.no_berm.Value = true;
            app.Panel_5 = uipanel(app.CreateProjectPSETab);
            app.ForcingUIAxes = uiaxes(app.Panel_5);
            zlabel(app.ForcingUIAxes, 'Z')
            app.ProjectForcingTab = uitab(app.TabGroup_2);
            app.ProjectForcingTab.Title = '5. Project Forcing';
            app.ProjectForcingScreeningLabel = uilabel(app.ProjectForcingTab);
            app.ProjectForcingScreeningLabel.Text = '5.1 Project Forcing Screening';
            app.PlotTypeDropDownLabel = uilabel(app.ProjectForcingTab);
            app.PlotTypeDropDownLabel.Text = 'Plot Type:';
            app.PlotTypeDropDown = uidropdown(app.ProjectForcingTab);
            app.PlotTypeDropDown.Items = {};
            app.PlotTypeDropDown.Placeholder = 'Waiting for data upload...';
            app.PlotTypeDropDown.Value = {};
            app.StormToDisplayLabel = uilabel(app.ProjectForcingTab);
            app.StormToDisplayLabel.Text = 'Storm To Display:';
            app.StormToDisplaySpinner = uispinner(app.ProjectForcingTab);
            app.StormToDisplaySpinner.Limits = [0 Inf];
            app.StormToDisplaySpinner.ValueDisplayFormat = '%.0f';
            app.AllStormsCheckBox = uicheckbox(app.ProjectForcingTab);
            app.AllStormsCheckBox.Text = 'All Storms';
            app.Panel_4 = uipanel(app.ProjectForcingTab);
            app.ForcingUIAxes_2 = uiaxes(app.Panel_4);
            zlabel(app.ForcingUIAxes_2, 'Z')
            app.ProjectForcingAdjustmentsLabel = uilabel(app.ProjectForcingTab);
            app.ProjectForcingAdjustmentsLabel.Text = '5.2 Project Forcing Adjustments';
            app.outpathButton_5 = uibutton(app.ProjectForcingTab, 'push');
            app.outpathButton_5.Icon = fullfile(pathToMLAPP, 'Logos', 'search_icon_w.svg');
            app.outpathButton_5.Text = '';
            app.outpathEditField_5 = uieditfield(app.ProjectForcingTab, 'text');
            app.outpathEditField_5.Editable = 'off';
            app.outpathEditField_5.Placeholder = 'CHS SWL regional bias file...';
            app.SLREditField_4Label_2 = uilabel(app.ProjectForcingTab);
            app.SLREditField_4Label_2.Text = 'CHS Bias File';
            app.Label_2 = uilabel(app.ProjectForcingTab);
            app.Label_2.Text = ':';
            app.DepthLimitationLabel = uilabel(app.ProjectForcingTab);
            app.DepthLimitationLabel.Text = 'Depth Limitation:';
            app.apply_depth_limitation_off = uicheckbox(app.ProjectForcingTab);
            app.apply_depth_limitation_off.ValueChangedFcn = createCallbackFcn(app, @apply_depth_limitation_offValueChanged, true);
            app.apply_depth_limitation_off.Text = 'Off';
            app.apply_depth_limitation_off.Value = true;
            app.apply_depth_limitation_on = uicheckbox(app.ProjectForcingTab);
            app.apply_depth_limitation_on.Text = 'On';
            app.PlotFIeldDropDownLabel = uilabel(app.ProjectForcingTab);
            app.PlotFIeldDropDownLabel.Text = 'Plot FIeld:';
            app.PlotFIeldDropDown = uidropdown(app.ProjectForcingTab);
            app.PlotFIeldDropDown.Items = {};
            app.PlotFIeldDropDown.Placeholder = 'Waiting for data upload...';
            app.PlotFIeldDropDown.Value = {};
            app.SLREditField_4Label = uilabel(app.ProjectForcingTab);
            app.SLREditField_4Label.Text = 'SLR:';
            app.SLREditField_4 = uieditfield(app.ProjectForcingTab, 'text');
            app.SLREditField_4.Editable = 'off';
            app.SLREditField_4.Enable = 'off';
            app.SLREditField_4.Placeholder = 'No tides...';
            app.SLREditField_4.Value = '0';
            app.noTidesEdtiField = uieditfield(app.ProjectForcingTab, 'text');
            app.noTidesEdtiField.Editable = 'off';
            app.noTidesEdtiField.Placeholder = 'No tides...';
            app.TidesEditField_4Label = uilabel(app.ProjectForcingTab);
            app.TidesEditField_4Label.Text = 'Tides:';
            app.tidalGageFileDonwloadPanel_2 = uipanel(app.ProjectForcingTab);
            app.tidalGageFileDonwloadPanel_2.Visible = 'off';
            app.outpathButton_3 = uibutton(app.tidalGageFileDonwloadPanel_2, 'push');
            app.outpathButton_3.Icon = fullfile(pathToMLAPP, 'Logos', 'search_icon_w.svg');
            app.outpathButton_3.Text = '';
            app.outpathEditField_3 = uieditfield(app.tidalGageFileDonwloadPanel_2, 'text');
            app.outpathEditField_3.Editable = 'off';
            app.outpathEditField_3.Placeholder = 'Upload tidal file...';
            app.tidalGageFileDonwloadPanel = uipanel(app.ProjectForcingTab);
            app.tidalGageFileDonwloadPanel.Visible = 'off';
            app.StationIDEditFieldLabel = uilabel(app.tidalGageFileDonwloadPanel);
            app.StationIDEditFieldLabel.Text = 'Station ID:';
            app.StationIDEditField = uieditfield(app.tidalGageFileDonwloadPanel, 'text');
            app.StationIDEditField.CharacterLimits = [7 7];
            app.StationIDEditField.Placeholder = '8735180';
            app.Button = uibutton(app.tidalGageFileDonwloadPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'Logos', 'plot_icon.svg');
            app.Button.Text = '';
            app.Lamp = uilamp(app.tidalGageFileDonwloadPanel);
            app.StationIDEditFieldLabel_2 = uilabel(app.tidalGageFileDonwloadPanel);
            app.StationIDEditFieldLabel_2.Text = 'Data Pull:';
            app.tidalGagePanel = uipanel(app.CenterPanel);
            app.tidalGagePanel.Title = 'NOAA Tidal Gage Metadata';
            app.tidalGagePanel.Visible = 'off';
            app.StartDateDatePickerLabel = uilabel(app.tidalGagePanel);
            app.StartDateDatePickerLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.tidalGagePanel);
            app.StartDateDatePicker.Limits = [datetime([2000 1 1]) datetime([9999 12 31])];
            app.DatumLabel = uilabel(app.tidalGagePanel);
            app.DatumLabel.Text = 'Datum:';
            app.DatumDropDown = uidropdown(app.tidalGagePanel);
            app.DatumDropDown.Items = {'NAVD88', 'MSL', 'Option 3', 'Option 4'};
            app.DatumDropDown.Value = 'NAVD88';
            app.UITable = uitable(app.tidalGagePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.PSEClassDropDownLabel_12 = uilabel(app.tidalGagePanel);
            app.PSEClassDropDownLabel_12.Text = '1. Choose start date for yearly tidal prediction & datum';
            app.PSEClassDropDownLabel_13 = uilabel(app.tidalGagePanel);
            app.PSEClassDropDownLabel_13.Text = 'Available Water Level Products For Requested Station';
            app.Panel_3 = uipanel(app.tidalGagePanel);
            app.ForcingUIAxes_3 = uiaxes(app.Panel_3);
            title(app.ForcingUIAxes_3, 'NOAA Gage ')
            xlabel(app.ForcingUIAxes_3, 'Date')
            ylabel(app.ForcingUIAxes_3, 'Elevation [m, NAVD88]')
            zlabel(app.ForcingUIAxes_3, 'Z')
            app.SubmitButton = uibutton(app.tidalGagePanel, 'state');
            app.SubmitButton.Text = 'Submit';
            app.PSEClassDropDownLabel_14 = uilabel(app.tidalGagePanel);
            app.PSEClassDropDownLabel_14.Text = '2. Submit to create StormSim tidal file';
            app.midPanelBlock = uiimage(app.CenterPanel);
            app.midPanelBlock.ImageSource = fullfile(pathToMLAPP, 'Logos', 'background_icon.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_6 = uipanel(app.RightPanel);
            app.SetupProjectResponsesRunStromSimLabel = uilabel(app.Panel_6);
            app.SetupProjectResponsesRunStromSimLabel.Text = '7. Set-up Project Responses & Run StromSim';
            app.Tree = uitree(app.Panel_6, 'checkbox');
            app.RunupR2Node = uitreenode(app.Tree);
            app.RunupR2Node.Text = 'Run-up (R2%)';
            app.OvertoppingqNode = uitreenode(app.Tree);
            app.OvertoppingqNode.Text = 'Overtopping (q)';
            app.TotalOvertoppingRateQNode = uitreenode(app.Tree);
            app.TotalOvertoppingRateQNode.Text = 'Total Overtopping Rate (Q)';
            app.PSEUncertainty = uitable(app.Panel_6);
            app.PSEUncertainty.ColumnName = {'Field'; 'Value'; 'Units'};
            app.PSEUncertainty.RowName = {};
            app.PSEUncertainty.ColumnSortable = [true true false];
            app.forcingUncertainty = uitable(app.Panel_6);
            app.forcingUncertainty.ColumnName = {'Field'; 'Value'; 'Units'};
            app.forcingUncertainty.RowName = {};
            app.forcingUncertainty.ColumnSortable = [true true false];
            app.forcingUncertainty.ColumnEditable = [false true false];
            app.SetupProjectUncertaintyLabel = uilabel(app.Panel_6);
            app.SetupProjectUncertaintyLabel.Text = '6. Set-up Project Uncertainty';
            app.PSEClassDropDownLabel_16 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_16.Text = 'PSE Responses To Compute:';
            app.PSEClassDropDownLabel_17 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_17.Text = 'Plots To Generate:';
            app.Tree_2 = uitree(app.Panel_6, 'checkbox');
            app.RunupR2Node_2 = uitreenode(app.Tree_2);
            app.RunupR2Node_2.Text = 'Run-up (R2%)';
            app.OvertoppingqNode_2 = uitreenode(app.Tree_2);
            app.OvertoppingqNode_2.Text = 'Overtopping (q)';
            app.TotalOvertoppingRateQNode_2 = uitreenode(app.Tree_2);
            app.TotalOvertoppingRateQNode_2.Text = 'Total Overtopping Rate (Q)';
            app.PSEClassDropDownLabel_15 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_15.Text = 'Submit Simulation:';
            app.PSEClassDropDownLabel_11 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_11.Text = 'Forcing Uncertainty:';
            app.restartForcingUncert = uibutton(app.Panel_6, 'push');
            app.restartForcingUncert.ButtonPushedFcn = createCallbackFcn(app, @restartForcingUncertButtonPushed, true);
            app.restartForcingUncert.Icon = fullfile(pathToMLAPP, 'Logos', 'rest_icon.png');
            app.restartForcingUncert.Text = '';
            app.PSEClassDropDownLabel_9 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_9.Text = 'PSE Responses Uncertainty:';
            app.restarPSEUncert = uibutton(app.Panel_6, 'push');
            app.restarPSEUncert.ButtonPushedFcn = createCallbackFcn(app, @restarPSEUncertPushed, true);
            app.restarPSEUncert.Icon = fullfile(pathToMLAPP, 'Logos', 'rest_icon.png');
            app.restarPSEUncert.Text = '';
            app.RunButton = uibutton(app.Panel_6, 'push');
            app.RunButton.Text = 'Run';
            app.Lamp_2 = uilamp(app.Panel_6);
            app.NotreadyLabel = uilabel(app.Panel_6);
            app.NotreadyLabel.Text = 'Not ready...';
            app.WorkflowDropDownLabel = uilabel(app.Panel_6);
            app.WorkflowDropDownLabel.Text = 'Workflow:';
            app.WorkflowDropDown = uidropdown(app.Panel_6);
            app.WorkflowDropDown.Items = {'StormSim: PROS', 'StormSim: LCS', 'StormSim: PROS-Frequency Base'};
            app.WorkflowDropDown.ItemsData = {'1', '2', '3'};
            app.WorkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @WorkflowDropDownValueChanged, true);
            app.WorkflowDropDown.Placeholder = 'Choose project transect...';
            app.WorkflowDropDown.Value = '1';
            app.PSEClassDropDownLabel_18 = uilabel(app.Panel_6);
            app.PSEClassDropDownLabel_18.Text = 'AEF/AEP Switch:';
            app.use_aep = uiswitch(app.Panel_6, 'slider');
            app.use_aep.Items = {'AEP', 'AEF'};
            app.use_aep.ItemsData = [1 0];
            app.use_aep.Value = 1;
            app.rightPanelBlock = uiimage(app.RightPanel);
            app.rightPanelBlock.ImageSource = fullfile(pathToMLAPP, 'Logos', 'background_icon.png');
            app.SourceDropDownLabel = uilabel(app.UIFigure);
            app.SourceDropDownLabel.Text = 'Source:';
            app.forcing_type = uidropdown(app.UIFigure);
            app.forcing_type.Items = {'Coastal Hazard System (CHS)'};
            app.forcing_type.ItemsData = [1 2];
            app.forcing_type.Placeholder = 'Choose project transect...';
            app.forcing_type.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end